classdef NewId < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Dialog                  matlab.ui.Figure
        Email                   matlab.ui.control.EditField
        EmailLabel              matlab.ui.control.Label
        AuthorEF                matlab.ui.control.EditField
        AuthorLabel             matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        CancelButton            matlab.ui.control.Button
        OkayButton              matlab.ui.control.Button
        GenotypeEditField       matlab.ui.control.EditField
        GenotypeEditFieldLabel  matlab.ui.control.Label
        SexDropDown             matlab.ui.control.DropDown
        SexDropDownLabel        matlab.ui.control.Label
        SpeciesDropDown         matlab.ui.control.DropDown
        SpeciesDropDownLabel    matlab.ui.control.Label
        descrEdit               matlab.ui.control.EditField
        descrEditLabel          matlab.ui.control.Label
        IdEdit                  matlab.ui.control.EditField
        Idtitlename30charLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp  % Description
        field % Description
        Values % Input values
        fl % Description
        exprs = '^([A-Za-z0-9])[\w-._]*$' % Regex strict
        exprr = '^([A-Za-z0-9])[\S]*$' % Regex relaxed
        entries % Present identifier array
        All % Description
        filtered % Description
    end
    
    methods (Access = private )
        
        function fieldlength( app )
                app.fl.project = 50;
                app.fl.dataset = 50;
                app.fl.subject = 50;
                app.fl.stimulus = 50;
                app.fl.condition = 30;
                app.fl.setup = 30;
                app.fl.investigator = 30;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, field, entries, position)
            app.CallingApp = Mainapp;
            app.field = field;  %the field type for this id.
            app.entries = entries; %the present array of ids.
            app.Values.id = '';
            %app.Values.descr = ['Enter a short description about this ' field];
            app.Values.descr = '';
            app.Values.author = '';
            app.Values.email = '';
            app.Dialog.Position(4) = 180;
            
            if strcmp(field, 'subject')
                app.Values.species = app.SpeciesDropDown.Value;
                app.Values.sex = app.SexDropDown.Value;
                app.Values.genotype = app.GenotypeEditField.Value;
                
                app.SpeciesDropDownLabel.Visible = "on";
                app.SpeciesDropDown.Visible = "on";
                app.SexDropDownLabel.Visible = "on";
                app.SexDropDown.Visible = "on";
                app.GenotypeEditFieldLabel.Visible = "on";
                app.GenotypeEditField.Visible = "on";
                app.UITable.ColumnName = {'id', 'species', 'sex', 'genotype'};
                app.All = app.CallingApp.AllSubjects;
                app.UITable.Data = app.CallingApp.AllSubjects;
                app.filtered = true(size(app.All,1),1);
                app.Dialog.Position(4) = 400;
                
            elseif strcmp(field, 'stimulus')
                app.UITable.ColumnName = {'id', 'descr'};
                app.All = app.CallingApp.AllStimuli;
                app.Dialog.Position(4) = 400;
                app.UITable.Data = app.All;
                app.filtered = true(size(app.All,1),1);
            
            elseif strcmp(field, 'project')
                app.AuthorEF.Visible = 'on';
                app.AuthorLabel.Visible = 'on';
            end
            
            if ~strcmp(field, 'investigator')
                app.descrEditLabel.Visible = "on";
                app.descrEdit.Visible = "on";
            else
                app.EmailLabel.Visible = "on";
                app.Email.Visible = "on";
            end
            
           % app.descrEdit.Value = app.Values.descr;
            app.Dialog.Name = ['Dialog input for ' field ];
            
            app.Dialog.Position([1,2]) = position;
            fieldlength(app)
            
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            % Call main app's public function
            
            if isempty(app.Values.id)
                 warndlg('Missing required id, please enter value.')
            elseif strcmp(app.field, 'investigator')
                if isempty(app.Values.email)
                    warndlg('Please add emmail adress')
                else 
                    newentry(app.CallingApp, app.field, app.Values);
                    delete(app)
                end
            elseif isempty(app.Values.descr)
                warndlg('Please add short description.')
            elseif strcmp(app.field, 'subject')                   
                if ~isempty(app.Values.species) && ~isempty(app.Values.sex) && ~isempty(app.Values.genotype)                   
                    newentry(app.CallingApp, app.field, app.Values);
                    % Delete the dialog box
                    delete(app)
                else
                    warndlg('Missing values, please update subject.')
                end
            else
                newentry(app.CallingApp, app.field, app.Values);
                % Delete the dialog box
                delete(app)
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            setbuttons(app.CallingApp, "on");
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: IdEdit
        function IdEditValueChanged(app, event)
            id = event.Value;
            lngth = app.fl.(app.field);           
            if any(strcmpi(id, app.entries))
                uialert(app.Dialog, ["Invalid entry, this identifier is already present as a " app.field], 'Duplicate Error')
                app.IdEdit.Value='';
            else
                [startIdx, endIdx] = regexp(id, app.exprs);
                if length(id) <= lngth && ~isempty(startIdx) && ~isempty(endIdx) && startIdx == 1 && endIdx == length(id)
                    app.Values.id = id;
                else 
                    uialert(app.Dialog, ["Error: Either too long, " ...
                                         "or contains invalid characters!" ...
                                         "Please, no spaces"], 'Invalid Error')
                end
            end
        end

        % Value changed function: descrEdit
        function descrEditValueChanged(app, event)
            app.Values.descr = app.descrEdit.Value;            
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            app.Values.species = app.SpeciesDropDown.Value;            
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)
            app.Values.sex = app.SexDropDown.Value;         
        end

        % Value changed function: GenotypeEditField
        function GenotypeEditFieldValueChanged(app, event)
            value = app.GenotypeEditField.Value;
            lngth = 30;
            [startIdx, endIdx] = regexp(value, app.exprr);
            if length(value) <= lngth && ~isempty(startIdx) && ~isempty(endIdx) && startIdx == 1 && endIdx == length(value)
                app.Values.genotype = value;
            else 
                uialert(app.Dialog, ["Error: contains invalid characters!" ...
                                     "Please, no spaces"], 'Error')
            end            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            Idx = event.Indices;
            row = Idx(1);
            if row > 0 && row <= size( app.All, 1)
                 rows = find(app.filtered);
                 record = app.All(rows(row), :);                
                 id = record{1};
                 %check if not already in list
                 if any(strcmpi(id, app.entries))
                    uialert(app.Dialog, ["Invalid entry, this identifier is already present as a " app.field], 'Duplicate Error')
                    app.IdEdit.Value='';
                 else  %not in list , fill fields 
                     app.IdEdit.Value = id;
                     app.Values.id = id;
                     if strcmp(app.field, 'stimulus')
                          app.descrEdit.Value = record{2};
                          app.Values.descr = record{2};
                     elseif strcmp(app.field, 'subject')
                         k = find(strcmpi(app.SpeciesDropDown.Items(), record{2}));
                         if isempty(k), k=1; end
                         app.SpeciesDropDown.Value = app.SpeciesDropDown.Items{k};
                         app.Values.species = app.SpeciesDropDown.Items{k};
                         k = find(strcmpi(app.SexDropDown.Items(), record{3}));
                         if isempty(k), k=1; end
                         app.SexDropDown.Value = app.SexDropDown.Items{k};
                         app.Values.sex = app.SexDropDown.Items{k};

                         app.GenotypeEditField.Value = record{4}; 
                         app.Values.genotype = record{4};
                     end
                 end
            end
        end

        % Value changing function: IdEdit
        function IdEditValueChanging(app, event)
            changingValue = event.Value;
            if any(strcmp(app.field, {'subject', 'stimulus'}))
                if ~isempty(changingValue)
                    x = regexpi({app.All{:,1}}, ['^' changingValue '\.*'],'match');
                    app.filtered = cellfun(@(i) ~isempty(i), x);
                    app.UITable.Data = app.All(app.filtered, :);
                else
                    app.filtered = true(size(app.All,1),1);
                    app.UITable.Data = app.All(:, :);
                end
            end
        end

        % Value changed function: AuthorEF
        function authorChanged(app, event)
            app.Values.author = app.AuthorEF.Value;
            
        end

        % Value changed function: Email
        function emailchangedfnc(app, event)

            changedValue = app.Email.Value;
            re = '^[a-zA-Z].[a-zA-Z0-9._%+-]+@nin.knaw.nl$';
            if ~isempty(changedValue)
                si = regexp(changedValue, re, 'once');
                if isempty(si) 
                    uialert(app.Dialog, ["Error: invalid email adress" ...
                                     "Please, change"], 'Error')
                    app.Email.Value = '';

                else
                    app.Values.email = changedValue;
                end
            end            


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Dialog = uifigure('Visible', 'off');
            app.Dialog.Name = 'Dialog Input for';
            app.Idtitlename30charLabel = uilabel(app.Dialog);
            app.Idtitlename30charLabel.Text = {'Id (title/name)'; '<30 char.'};
            app.IdEdit = uieditfield(app.Dialog, 'text');
            app.IdEdit.ValueChangedFcn = createCallbackFcn(app, @IdEditValueChanged, true);
            app.IdEdit.ValueChangingFcn = createCallbackFcn(app, @IdEditValueChanging, true);
            app.descrEditLabel = uilabel(app.Dialog);
            app.descrEditLabel.Visible = 'off';
            app.descrEditLabel.Text = {'Short description'; '<255 char.'};
            app.descrEdit = uieditfield(app.Dialog, 'text');
            app.descrEdit.ValueChangedFcn = createCallbackFcn(app, @descrEditValueChanged, true);
            app.descrEdit.Visible = 'off';
            app.descrEdit.Placeholder = 'Add a short description';
            app.SpeciesDropDownLabel = uilabel(app.Dialog);
            app.SpeciesDropDownLabel.Visible = 'off';
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.Dialog);
            app.SpeciesDropDown.Items = {'mouse', 'rat', 'fish', 'monkey', 'human', 'Other'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Visible = 'off';
            app.SpeciesDropDown.Value = 'mouse';
            app.SexDropDownLabel = uilabel(app.Dialog);
            app.SexDropDownLabel.Visible = 'off';
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.Dialog);
            app.SexDropDown.Items = {'M', 'F', 'U'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Visible = 'off';
            app.SexDropDown.Value = 'M';
            app.GenotypeEditFieldLabel = uilabel(app.Dialog);
            app.GenotypeEditFieldLabel.Visible = 'off';
            app.GenotypeEditFieldLabel.Text = 'Genotype';
            app.GenotypeEditField = uieditfield(app.Dialog, 'text');
            app.GenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @GenotypeEditFieldValueChanged, true);
            app.GenotypeEditField.Visible = 'off';
            app.GenotypeEditField.Value = 'none';
            app.OkayButton = uibutton(app.Dialog, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.Dialog, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UITable = uitable(app.Dialog);
            app.UITable.ColumnName = {'Name(Id)'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AuthorLabel = uilabel(app.Dialog);
            app.AuthorLabel.Visible = 'off';
            app.AuthorLabel.Text = 'Author';
            app.AuthorEF = uieditfield(app.Dialog, 'text');
            app.AuthorEF.ValueChangedFcn = createCallbackFcn(app, @authorChanged, true);
            app.AuthorEF.Visible = 'off';
            app.AuthorEF.Placeholder = 'your name';
            app.EmailLabel = uilabel(app.Dialog);
            app.EmailLabel.Visible = 'off';
            app.EmailLabel.Text = 'Email';
            app.Email = uieditfield(app.Dialog, 'text');
            app.Email.ValueChangedFcn = createCallbackFcn(app, @emailchangedfnc, true);
            app.Email.Visible = 'off';
            app.Email.Placeholder = 'a.name@nin.knaw.nl';
            % Show the figure after all components are created
            app.Dialog.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewId(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Dialog)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Dialog)
        end
    end
end