classdef fydAccess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FydAccessUIFigure      matlab.ui.Figure
        HelpButton             matlab.ui.control.Button
        HTML                   matlab.ui.control.HTML
        CloseBtn               matlab.ui.control.Button
        setupdd                matlab.ui.control.DropDown
        setupLabel             matlab.ui.control.Label
        url_ed                 matlab.ui.control.EditField
        urlfilterLabel         matlab.ui.control.Label
        Tree                   matlab.ui.container.CheckBoxTree
        Root                   matlab.ui.container.TreeNode
        DisplayButton          matlab.ui.control.Button
        labdd                  matlab.ui.control.DropDown
        labLabel               matlab.ui.control.Label
        stimulusdd             matlab.ui.control.DropDown
        stimulusDropDownLabel  matlab.ui.control.Label
        subjectdd              matlab.ui.control.DropDown
        subjectDropDownLabel   matlab.ui.control.Label
        exconddd               matlab.ui.control.DropDown
        excondDropDownLabel    matlab.ui.control.Label
        datasetdd              matlab.ui.control.DropDown
        datasetDropDownLabel   matlab.ui.control.Label
        projectdd              matlab.ui.control.DropDown
        projectDropDownLabel   matlab.ui.control.Label
        file_ed                matlab.ui.control.EditField
        filefilterLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        dbpar % Description
        DB % Description
        datastore % output values to calling environment
        fydcache % fyd fields
        AllSubjects % Description
        AllStimuli % Description
        folders % Description
        files % Description
        filter % Description
        labnames % Description
        urls % Description
    end
       
    methods (Access = public)
        

    end
    
    methods (Access = private)

        
        function valid = getlab(app, strlab)

            valid = false;         
            idx = strcmp(strlab, {app.labnames{1,:}});
            if any(idx)
                prmfile = ['nhi_fyd_' app.labnames{2,idx} 'parms'];
                if exist(prmfile, 'file')
                    app.dbpar = eval(prmfile);
                    valid = true;
                end
            end
            if ~valid
                uialert(app.FydAccessUIFigure, ["No parameterfile found for: " strlab "."...
                  "Please contact Chris van der Togt to obtain this file." ...
                  "email:c.vandertogt@nin.knaw.nl"], 'Warning')
            end
        end
        
        function initproject(app)
            %app.InfoPanel.Title = ['Enter fields for ' app.dbpar.Database]; 
             
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.projects = mysql('SELECT projectid FROM projects');
            mysql('close')
            
            if ~isempty(app.DB.projects)
                app.projectdd.Items = sort(app.DB.projects);
                app.projectdd.Enable = 'on';
                if ~isempty(app.fydcache.project) && any(ismember(app.DB.projects, app.fydcache.project))
                    app.projectdd.Value = app.fydcache.project;
                else
                    app.fydcache.project = app.projectdd.Value;
                end
                initdataset(app);
                initsubject(app);
                initstim(app);
                initsetup(app);
            else
                app.projectdd.Items = {};
                app.projectdd.Enable = 'off';
                app.datasetdd.Items = {};
                app.datasetdd.Enable = 'off';
                app.exconddd.Items = {};
                app.exconddd.Enable = 'off';   
                app.subjectdd.Items = {};
                app.subjectdd.Enable = 'off';
                app.stimulusdd.Items = {};
                app.stimulusdd.Enable = 'off';
            end
        end
        
        function initdataset(app)
            
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.datasets = mysql(['SELECT datasetid FROM datasets WHERE project= "' app.fydcache.project '"']);
                        
            mysql('close')
            if ~isempty(app.DB.datasets)
                app.datasetdd.Items =  ['-'; app.DB.datasets];
                app.datasetdd.Enable = 'on';
                 if ~isempty(app.fydcache.dataset) && any(ismember(app.DB.datasets, app.fydcache.dataset))
                    app.datasetdd.Value = app.fydcache.dataset;
                 else 
                    app.fydcache.dataset = app.datasetdd.Value;
                 end
                initcondition(app)
            else
                app.datasetdd.Items = {};
                app.datasetdd.Enable = 'off';
                app.exconddd.Items = {};
                app.exconddd.Enable = 'off';
            end
        end
        
        function initcondition(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.conditions = mysql(['SELECT conditionid FROM conditions WHERE dataset = "' app.fydcache.dataset '"' ...
                                   'and project = "' app.fydcache.project '" ']);
            mysql('close')
            
            if ~isempty(app.DB.conditions)
                app.exconddd.Items = [ '-'; app.DB.conditions];
                app.exconddd.Enable = 'on';
                if ~isempty(app.fydcache.excond) && any(ismember(app.DB.conditions, app.fydcache.excond))
                    app.exconddd.Value = app.fydcache.excond;
                else
                    app.fydcache.excond = app.exconddd.Value;
                end
            else
                app.exconddd.Items = {};
                app.exconddd.Enable = 'off';
            end
            
        end
        
        function initsubject(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.subjects = mysql(['SELECT subject FROM projects_subjects WHERE project = "' app.fydcache.project '"']);
            app.AllSubjects = mysql(['SELECT subjectid, species, sex, genotype FROM subjects']);
            mysql('close')
            
            if ~isempty(app.DB.subjects)
                app.subjectdd.Items = [ '-'; app.DB.subjects];
                app.subjectdd.Enable = 'on';
                if ~isempty(app.fydcache.subject)  && any(ismember(app.DB.subjects, app.fydcache.subject))
                    app.subjectdd.Value = app.fydcache.subject;
                else
                    app.fydcache.subject = app.subjectdd.Value;
                end
            else
                app.subjectdd.Items = {};
                app.subjectdd.Enable = 'off';
            end
            
        end
        
        function initstim(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.stims = mysql(['SELECT stimulus FROM projects_stimulus WHERE project = "' app.fydcache.project '"']);
            app.AllStimuli = mysql(['SELECT stimulusid, shortdescr FROM stimulus']);
            
            mysql('close')
            
            if ~isempty(app.DB.stims)
                app.stimulusdd.Items = [ '-'; app.DB.stims];
                app.stimulusdd.Enable = 'on';
                if ~isempty(app.fydcache.stimulus) && any(ismember(app.DB.stims, app.fydcache.stimulus))
                    app.stimulusdd.Value = app.fydcache.stimulus;
                else
                    app.fydcache.stimulus = app.stimulusdd.Value;
                end
            else
                app.stimulusdd.Items = {};
                app.stimulusdd.Enable = 'off';
            end
            
        end
        
        function url = geturl(~, record)          
            switch record{3}
                case {'mnt', 'VS03', 'VS01'}
                    url = fullfile(strrep(record{1}, 'mnt', ''));
                    
                otherwise
                    url = fullfile(strrep(record{1}, './', ['\\' record{3} '\']));
            end
            url = fileparts(url);
        end
        
        function initsetup(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.setups = mysql(['SELECT setupid FROM setups']);

            mysql('close')
            
            if ~isempty(app.DB.setups)
                app.setupdd.Items = [ '-'; app.DB.setups];
                app.setupdd.Enable = 'on';
                if ~isempty(app.fydcache.setup) && any(ismember(app.DB.setups, app.fydcache.setup))
                    app.setupdd.Value = app.fydcache.setup;
                else
                    app.fydcache.setup = app.setupdd.Value;
                end
            else
                app.setupdd.Items = {};
                app.setupdd.Enable = 'off';
            end
            
        end
        
        function filter = makefilter(app, id)
            filter = '';  
            filtList = strsplit(app.filter.file, ',');
            ln = length(filtList);
            if any(strcmp(filtList, 'sessionid'))
                filter = [id];
                if ln == 1
                    filter = [id '*'];
                end
            elseif ~isempty(filtList)
                filter = strtrim(filtList{1});
            end
            if ln > 1
                filter = [filter strtrim(filtList{2})];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datastore, labnames)
            
            app.datastore = datastore; 
            app.labnames = labnames;
           
            app.DB.projects = [];
            app.DB.datasets = [];
            app.DB.subjects = [];
            app.DB.conditions = [];
 %           app.DB.investigators = [];
            app.DB.setups = [];
            app.DB.stims = [];
          
            %Initialization and info retrieval
            strlab = '-';
            if exist('fydcache.mat', 'file')
                fyd = load('fydcache.mat');
                app.fydcache = fyd.fydcache; 
                if ~isempty(fyd.fydcache)
                    strlab = app.fydcache.lab;
                end
            else
                %look for local parmfiles
                for i = 1:length(app.labnames)
                    prmfile = ['nhi_fyd_' app.labnames{2,i} 'parms'];
                    if exist(prmfile, 'file')
                        strlab = app.labnames{1,i};
                        app.dbpar = eval(prmfile);
                    end                   
                end
                app.fydcache.lab=strlab;
                app.fydcache.project='';
                app.fydcache.dataset='';
                app.fydcache.subject='';
                app.fydcache.excond='';
                app.fydcache.stimulus='';
                app.fydcache.setup='';
                app.fydcache.url='-';  

            end 
            if any(ismember(app.labdd.Items, strlab))           
                app.labdd.Value = strlab;
            else
                uialert(app.FydAccessUIFigure, 'No valid database parameters found', 'Error')
                return
            end
            
            app.filter.file = 'sessionid';
            app.url_ed.Value = app.fydcache.url;
            
            if getlab(app, strlab)
                initproject(app)
            end
            
            %updateoutput(app)
            
        end

        % Value changed function: labdd
        function labddValueChanged(app, event)
            app.fydcache.lab = app.labdd.Value;
            app.fydcache.project='';
            app.fydcache.dataset='';
            app.fydcache.subject='';
            app.fydcache.excond='';
            app.fydcache.stimulus='';
            app.fydcache.setup='';
            app.fydcache.url='-';
            
            if getlab(app, app.fydcache.lab)
                initproject(app)
            end
            
        end

        % Callback function: CloseBtn, FydAccessUIFigure
        function UIFigureCloseRequest(app, event)
            nodes = app.Tree.CheckedNodes;
            myurls = {};
            for i = 1:length(nodes)
                if ~isempty(nodes(i).NodeData)
                    idx = nodes(i).NodeData;
                    myurls(length(myurls)+1) = {[app.urls(idx).url '\' nodes(i).Text]};
                end                 
            end
            app.datastore.fields = app.fydcache;
            app.datastore.fields.urls = myurls;

            
            %assignin('base','fileList', myurls') %caller
            
            fydcache = app.fydcache; %#ok<ADPROPLC> 
            save fydcache.mat fydcache  
            % app.datastore.fields = app.fydcache;
            delete(app)
            
        end

        % Value changed function: projectdd
        function projectddValueChanged(app, event)
            app.fydcache.project = app.projectdd.Value;
            app.fydcache.dataset ='';
            app.fydcache.subject ='';
            app.fydcache.excond ='';
            app.fydcache.stimulus ='';
            app.fydcache.setup='';
            app.fydcache.url ='-';
            
            initdataset(app);
            initsubject(app);
            initstim(app);  
            initsetup(app);
            
        end

        % Value changed function: datasetdd
        function datasetddValueChanged(app, event)
            app.fydcache.dataset = app.datasetdd.Value;
            app.fydcache.excond =''; 
            initcondition(app);
        end

        % Value changed function: exconddd
        function excondddValueChanged(app, event)
            app.fydcache.excond = app.exconddd.Value;
            
        end

        % Value changed function: subjectdd
        function subjectddValueChanged(app, event)
            app.fydcache.subject = app.subjectdd.Value;
            
        end

        % Value changed function: stimulusdd
        function stimulusddValueChanged(app, event)
            app.fydcache.stimulus = app.stimulusdd.Value;
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            % retrieve sessions based on selected fields
            
            d = uiprogressdlg(app.FydAccessUIFigure,'Title','Retrieving data', 'Indeterminate','on');
            drawnow
            
            flds = fieldnames(app.fydcache);
            vals = struct2cell(app.fydcache);

       %%     %delete(app.folders and app.files)
             for i=1:length(app.folders)
                 delete(app.folders(i).node)
             end 
             app.folders = [];
             
             for i=1:length(app.files)
                 delete(app.files(i).node)
             end 
             app.files = [];
      %%
      % construct query from input
            query = [];
            for i = 1:length(flds)
                fld = flds{i};
                val = vals{i};
                
                if strcmp(val, '-') || isempty(val) || strcmp(fld, 'lab')                    
                elseif strcmp(fld, 'url')   
                    query = [query ' AND ' fld ' LIKE "%' val '%"'];
                else
                    if isempty(query)
                        query = [fld ' = "' val '"'];
                    else
                        query = [query ' AND ' fld ' = "' val '"'];
                    end 
                end
            end
  %apply query on database
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            records = mysql(['SELECT url, sessionid, server FROM sessions WHERE ' query ]);           
            mysql('close')
  
  % process output from database
            Cnt = 1;
            if ~isempty(records) 
                app.urls = struct('url', [], 'id', []); 
                app.folders = struct('node', []);
                app.files = struct('node', []);
                
                for i = 1:size(records,1)
                        url = geturl(app, records(i,:));
                        id =  records{i,2};
                        app.urls(i).id = id;
                        app.urls(i).url = url;
                        app.folders(i).node = uitreenode(app.Root);
                        app.folders(i).node.Text = id;
                        
                        myfilter = makefilter(app, id);
                        searchpath = [url '\' myfilter];
                        strpath = fileparts(searchpath);
                        
                        if exist(strpath, 'dir')
                            filesIn = dir(searchpath);

                            for j = 1:length(filesIn)
                                if ~filesIn(j).isdir
                                    app.files(Cnt).node = uitreenode(app.folders(i).node);
                                    app.files(Cnt).node.Text = filesIn(j).name;
                                    app.files(Cnt).node.NodeData = i;
                                    Cnt = Cnt + 1;
                                end
                            end
                        else
                            disp([strpath ' :not accessible.'])
                        end

                end
                expand(app.Root)
                drawnow;
            end           
            close(d)
        end

        % Value changed function: file_ed
        function file_edValueChanged(app, event)
            app.filter.file = app.file_ed.Value;
            
        end

        % Value changed function: setupdd
        function setupddValueChanged(app, event)
            app.fydcache.setup = app.setupdd.Value;
            
        end

        % Value changed function: url_ed
        function url_edValueChanged(app, event)
            app.fydcache.url = app.url_ed.Value;
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            info = infofydaccess();
             % wait until the figure closes
             waitfor(info)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FydAccessUIFigure = uifigure('Visible', 'off');
            app.FydAccessUIFigure.Name = 'FydAccess';
            app.FydAccessUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.filefilterLabel = uilabel(app.FydAccessUIFigure);
            app.filefilterLabel.Text = 'file filter';
            app.file_ed = uieditfield(app.FydAccessUIFigure, 'text');
            app.file_ed.ValueChangedFcn = createCallbackFcn(app, @file_edValueChanged, true);
            app.file_ed.Value = 'sessionid';
            app.projectDropDownLabel = uilabel(app.FydAccessUIFigure);
            app.projectDropDownLabel.Text = 'project';
            app.projectdd = uidropdown(app.FydAccessUIFigure);
            app.projectdd.Items = {'-'};
            app.projectdd.ValueChangedFcn = createCallbackFcn(app, @projectddValueChanged, true);
            app.projectdd.Value = '-';
            app.datasetDropDownLabel = uilabel(app.FydAccessUIFigure);
            app.datasetDropDownLabel.Text = 'dataset';
            app.datasetdd = uidropdown(app.FydAccessUIFigure);
            app.datasetdd.Items = {'-'};
            app.datasetdd.ValueChangedFcn = createCallbackFcn(app, @datasetddValueChanged, true);
            app.datasetdd.Placeholder = 'select dataset';
            app.datasetdd.Value = '-';
            app.excondDropDownLabel = uilabel(app.FydAccessUIFigure);
            app.excondDropDownLabel.Text = 'excond';
            app.exconddd = uidropdown(app.FydAccessUIFigure);
            app.exconddd.Items = {'-'};
            app.exconddd.ValueChangedFcn = createCallbackFcn(app, @excondddValueChanged, true);
            app.exconddd.Placeholder = 'select condition';
            app.exconddd.Value = '-';
            app.subjectDropDownLabel = uilabel(app.FydAccessUIFigure);
            app.subjectDropDownLabel.Text = 'subject';
            app.subjectdd = uidropdown(app.FydAccessUIFigure);
            app.subjectdd.Items = {'-'};
            app.subjectdd.ValueChangedFcn = createCallbackFcn(app, @subjectddValueChanged, true);
            app.subjectdd.Placeholder = 'select subject';
            app.subjectdd.Value = '-';
            app.stimulusDropDownLabel = uilabel(app.FydAccessUIFigure);
            app.stimulusDropDownLabel.Text = 'stimulus';
            app.stimulusdd = uidropdown(app.FydAccessUIFigure);
            app.stimulusdd.Items = {'-'};
            app.stimulusdd.ValueChangedFcn = createCallbackFcn(app, @stimulusddValueChanged, true);
            app.stimulusdd.Placeholder = 'select stimulus';
            app.stimulusdd.Value = '-';
            app.labLabel = uilabel(app.FydAccessUIFigure);
            app.labLabel.Text = 'lab';
            app.labdd = uidropdown(app.FydAccessUIFigure);
            app.labdd.Items = {'roelfsemalab', 'leveltlab', 'willuhnlab', 'heimellab', 'socialbrainlab', 'dezeeuwlab', 'kolelab', 'vansomerenlab', 'Saltalab', 'lohmannlab', 'kamermanslab', 'kalsbeeklab', 'verhagenlab', 'testlab'};
            app.labdd.ValueChangedFcn = createCallbackFcn(app, @labddValueChanged, true);
            app.labdd.Value = 'roelfsemalab';
            app.DisplayButton = uibutton(app.FydAccessUIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.Tree = uitree(app.FydAccessUIFigure, 'checkbox');
            app.Root = uitreenode(app.Tree);
            app.Root.Text = 'Treebase';
            app.urlfilterLabel = uilabel(app.FydAccessUIFigure);
            app.urlfilterLabel.Text = 'url filter';
            app.url_ed = uieditfield(app.FydAccessUIFigure, 'text');
            app.url_ed.ValueChangedFcn = createCallbackFcn(app, @url_edValueChanged, true);
            app.url_ed.Placeholder = 'path expression';
            app.url_ed.Value = '-';
            app.setupLabel = uilabel(app.FydAccessUIFigure);
            app.setupLabel.Text = 'setup';
            app.setupdd = uidropdown(app.FydAccessUIFigure);
            app.setupdd.Items = {'-'};
            app.setupdd.ValueChangedFcn = createCallbackFcn(app, @setupddValueChanged, true);
            app.setupdd.Placeholder = 'select server';
            app.setupdd.Value = '-';
            app.CloseBtn = uibutton(app.FydAccessUIFigure, 'push');
            app.CloseBtn.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseBtn.Text = 'Save&Close';
            app.HTML = uihtml(app.FydAccessUIFigure);
            app.HTML.HTMLSource = '<p style="font-family:arial;font-size:12"><span>Constrain selection as much as possible to limit search time. See help for more tips</span></p>';
            app.HelpButton = uibutton(app.FydAccessUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.FydAccessUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fydAccess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FydAccessUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FydAccessUIFigure)
        end
    end
end