classdef getFYDfields < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FYD                        matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Sessionnr                  matlab.ui.control.Spinner
        SessionnrSpinnerLabel      matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        LabDropDown                matlab.ui.control.DropDown
        LabDropDownLabel           matlab.ui.control.Label
        CloseButton                matlab.ui.control.Button
        InvestBtnNew               matlab.ui.control.Button
        SetupBtnNew                matlab.ui.control.Button
        StimBtnNew                 matlab.ui.control.Button
        ConditionBtnNew            matlab.ui.control.Button
        SubjectBtnNew              matlab.ui.control.Button
        DatasetBtnNew              matlab.ui.control.Button
        ProjectBtnNew              matlab.ui.control.Button
        SessionIdEdit              matlab.ui.control.EditField
        SessionIdEditFieldLabel    matlab.ui.control.Label
        LogfileEdit                matlab.ui.control.EditField
        LogfileEditFieldLabel      matlab.ui.control.Label
        DatePicker                 matlab.ui.control.DatePicker
        DateDatePickerLabel        matlab.ui.control.Label
        InvestigatorDropDown       matlab.ui.control.DropDown
        InvestigatorDropDownLabel  matlab.ui.control.Label
        SetupDropDown              matlab.ui.control.DropDown
        SetupDropDownLabel         matlab.ui.control.Label
        StimulusDropDown           matlab.ui.control.DropDown
        StimulusDropDownLabel      matlab.ui.control.Label
        ConditionDropDown          matlab.ui.control.DropDown
        ConditionDropDownLabel     matlab.ui.control.Label
        SubjectDropDown            matlab.ui.control.DropDown
        SubjectDropDownLabel       matlab.ui.control.Label
        DatasetDropDown            matlab.ui.control.DropDown
        DatasetDropDownLabel       matlab.ui.control.Label
        ProjectDropDown            matlab.ui.control.DropDown
        ProjectDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
       datastore % output values to calling environment
        theme='dark' % Description
    end
    
    properties (Access = public)
        dbpar % Description
        DB % database tables
        fydsaved % Description
        AllSubjects % Description
        AllStimuli % Description
    end
    
    
    methods (Access = private)
        % get labs and sets choice after initialization
        function valid = getlab(app, strlab)

            valid = false;
            if strcmp(strlab, 'Roelfsemalab') && exist('nhi_fyd_VCparms.m', 'file')
                app.dbpar = nhi_fyd_VCparms();
                valid = true;
            elseif strcmp(strlab, 'Leveltlab') && exist('nhi_fyd_MVPparms.m', 'file')  
                app.dbpar = nhi_fyd_MVPparms();
                valid = true;
            elseif strcmp(strlab, 'Willuhnlab') && exist('nhi_fyd_NandBparms.m', 'file') 
                app.dbpar = nhi_fyd_NandBparms();
                valid = true;
            elseif strcmp(strlab, 'Heimellab') && exist('nhi_fyd_CSFparms.m', 'file')     
                app.dbpar = nhi_fyd_CSFparms();
                valid = true;
            elseif strcmp(strlab, 'Socialbrainlab') && exist('nhi_fyd_SBLparms.m', 'file')  
                app.dbpar = nhi_fyd_SBLparms();
                valid = true;
            elseif strcmp(strlab, 'Dezeeuwlab') && exist('nhi_fyd_CCCparms.m', 'file') 
                app.dbpar = nhi_fyd_CCCparms();
                valid = true;
            elseif strcmp(strlab, 'Kolelab') && exist('nhi_fyd_AXSparms.m', 'file')  
                app.dbpar = nhi_fyd_AXSparms();
                valid = true;
            elseif strcmp(strlab, 'vanSomerenlab') && exist('nhi_fyd_SandCparms.m', 'file') 
                app.dbpar = nhi_fyd_SandCparms();
                valid = true;
            elseif strcmp(strlab, 'Saltalab') && exist('nhi_fyd_NandNparms.m', 'file') 
                app.dbpar = nhi_fyd_NandNparms();
                valid = true;
            elseif strcmp(strlab, 'Lohmannlab') && exist('nhi_fyd_SNDparms.m', 'file') 
                app.dbpar = nhi_fyd_SNDparms();
                valid = true;
            elseif strcmp(strlab, 'Kalsbeeklab') && exist('nhi_fyd_HYPparms.m', 'file') 
                app.dbpar = nhi_fyd_HYPparms();
                valid = true; 
            elseif strcmp(strlab, 'Verhagenlab') && exist('nhi_fyd_NRGparms.m', 'file') 
                app.dbpar = nhi_fyd_NRGparms();
                valid = true; 
           elseif strcmp(strlab, 'Siclarilab') && exist('nhi_fyd_SandDparms.m', 'file') 
                app.dbpar = nhi_fyd_SandDparms();
                valid = true; 
           elseif strcmp(strlab, 'Huitingalab') && exist('nhi_fyd_IMMparms.m', 'file') 
                app.dbpar = nhi_fyd_IMMparms();
                valid = true; 
            elseif strcmp(strlab, 'Kamermanslab') && exist('nhi_fyd_RSPparms.m', 'file') 
                app.dbpar = nhi_fyd_RSPparms();
                valid = true;
           elseif strcmp(strlab, 'Examplelab') && exist('nhi_fyd_TMparms.m', 'file') 
                app.dbpar = nhi_fyd_TMparms();
                valid = true;
            else
                uialert(app.FYD, ["No parameterfile found for: " strlab "."...
                                  "Please contact Chris van der Togt to obtain this file." ...
                                  "email:c.vandertogt@nin.knaw.nl"], 'Error')
            end
            
        end
        
        %initialize projects, calls initialize datasets, subjects, and stimuli
        function ret = initproject(app)
            ret = true;
            app.Panel.Title = ['Enter fields for ' app.dbpar.Database];
            try 
                dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            catch err
                disp(['No connection with mysql server: ' err.message])
                ret = false;
                return
            end

            dbdb = mysql('use', app.dbpar.Database);
            app.DB.projects = mysql('SELECT projectid FROM projects');
            mysql('close')
            
            if ~isempty(app.DB.projects)
                app.ProjectDropDown.Items = sort(app.DB.projects);
                app.ProjectDropDown.Enable = 'on';
                if ~isempty(app.fydsaved.project) && any(ismember(app.DB.projects, app.fydsaved.project))
                    app.ProjectDropDown.Value = app.fydsaved.project;
                else
                    app.fydsaved.project = app.ProjectDropDown.Value;
                end
                initdataset(app);
                initsubject(app);
                initstim(app)
            else
                app.ProjectDropDown.Items = {};
                app.ProjectDropDown.Enable = 'off';
                app.DatasetDropDown.Items = {};
                app.DatasetDropDown.Enable = 'off';
                app.ConditionDropDown.Items = {};
                app.ConditionDropDown.Enable = 'off';   
                app.SubjectDropDown.Items = {};
                app.SubjectDropDown.Enable = 'off';
                app.StimulusDropDown.Items = {};
                app.StimulusDropDown.Enable = 'off';
            end
        end
        
        %initialize datasets, calls initialize conditions 
        function initdataset(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.datasets = mysql(['SELECT datasetid FROM datasets WHERE project= "' app.fydsaved.project '"']);
                        
            mysql('close')
            if ~isempty(app.DB.datasets)
                app.DatasetDropDown.Items = app.DB.datasets;
                app.DatasetDropDown.Enable = 'on';
                 if ~isempty(app.fydsaved.dataset) && any(ismember(app.DB.datasets, app.fydsaved.dataset))
                    app.DatasetDropDown.Value = app.fydsaved.dataset;
                 else 
                    app.fydsaved.dataset = app.DatasetDropDown.Value;
                 end
                 initcondition(app)
            else
                app.DatasetDropDown.Items = {};
                app.DatasetDropDown.Enable = 'off';
                app.ConditionDropDown.Items = {};
                app.ConditionDropDown.Enable = 'off';
            end
        end
        
        %initialize conditions
        function initcondition(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.conditions = mysql(['SELECT conditionid FROM conditions WHERE dataset = "' app.fydsaved.dataset '"' ...
                                   'and project = "' app.fydsaved.project '" ']);
            mysql('close')
            
            if ~isempty(app.DB.conditions)
                app.ConditionDropDown.Items = app.DB.conditions;
                app.ConditionDropDown.Enable = 'on';
                if ~isempty(app.fydsaved.condition) && any(ismember(app.DB.conditions, app.fydsaved.condition))
                    app.ConditionDropDown.Value = app.fydsaved.condition;
                else
                    app.fydsaved.condition = app.ConditionDropDown.Value;
                end
            else
                app.ConditionDropDown.Items = {};
                app.ConditionDropDown.Enable = 'off';
            end
        end
        
        %initialize subjects
        function initsubject(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.subjects = mysql(['SELECT subject FROM projects_subjects WHERE project = "' app.fydsaved.project '"']);
            app.AllSubjects = mysql(['SELECT subjectid, species, sex, genotype FROM subjects']);
            mysql('close')
            
            if ~isempty(app.DB.subjects)
                app.SubjectDropDown.Items = app.DB.subjects;
                app.SubjectDropDown.Enable = 'on';
                if ~isempty(app.fydsaved.subject)  && any(ismember(app.DB.subjects, app.fydsaved.subject))
                    app.SubjectDropDown.Value = app.fydsaved.subject;
                else
                    app.fydsaved.subject = app.SubjectDropDown.Value;
                end
            else
                app.SubjectDropDown.Items = {};
                app.SubjectDropDown.Enable = 'off';
            end
        end
        %initialize stimuli
        function initstim(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.stims = mysql(['SELECT stimulus FROM projects_stimulus WHERE project = "' app.fydsaved.project '"']);
            app.AllStimuli = mysql(['SELECT stimulusid, shortdescr FROM stimulus']);
            
            mysql('close')
            
            if ~isempty(app.DB.stims)
                app.StimulusDropDown.Items = app.DB.stims;
                app.StimulusDropDown.Enable = 'on';
                if ~isempty(app.fydsaved.stimulus) && any(ismember(app.DB.stims, app.fydsaved.stimulus))
                    app.StimulusDropDown.Value = app.fydsaved.stimulus;
                else
                    app.fydsaved.stimulus = app.StimulusDropDown.Value;
                end
            else
                app.StimulusDropDown.Items = {};
                app.StimulusDropDown.Enable = 'off';
            end
        end
        %initialize setups
        function initsetup(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.setups = mysql(['SELECT setupid FROM setups']);

            mysql('close')
            
            if ~isempty(app.DB.setups)
                app.SetupDropDown.Items = app.DB.setups;
                app.SetupDropDown.Enable = 'on';
                if ~isempty(app.fydsaved.setup) && any(ismember(app.DB.setups, app.fydsaved.setup))
                    app.SetupDropDown.Value = app.fydsaved.setup;
                else
                    app.fydsaved.setup = app.SetupDropDown.Value;
                end
            else
                app.SetupDropDown.Items = {};
                app.SetupDropDown.Enable = 'off';
            end
        end
        %initialize investigators
        function initinvestigator(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.investigators = mysql(['SELECT investigatorid FROM investigator']);

            mysql('close')
            
            if ~isempty(app.DB.investigators)
                app.InvestigatorDropDown.Items = app.DB.investigators;
                app.InvestigatorDropDown.Enable = 'on';
                if ~isempty(app.fydsaved.investigator) && any(ismember(app.DB.investigators, app.fydsaved.investigator))
                    app.InvestigatorDropDown.Value = app.fydsaved.investigator;
                else
                    app.fydsaved.investigator = app.InvestigatorDropDown.Value;
                end 
            else
                app.InvestigatorDropDown.Items = {};
                app.InvestigatorDropDown.Enable = 'off';
            end
        end
        
        %updates values for sessionID
        function updateoutput(app)
            sessdate = strrep(app.fydsaved.date, '-', '');
            sessionid = [app.fydsaved.subject '_' sessdate '_' num2str(app.fydsaved.sessnr, '%03d') ];

            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            rec = mysql(['SELECT idx FROM sessions WHERE sessionid="' sessionid '"']);
            mysql('close')
            if ~isempty(rec)
                errordlg('This will generate a duplicate sessionid, Please change!')
            else
                app.fydsaved.sessionid = sessionid;
                app.SessionIdEdit.Value = sessionid;
                % app.fydsaved.logfile = [ app.SessionIdEdit.Value '_log.m' ]; 
                % app.LogfileEdit.Value = app.fydsaved.logfile;   
            end        
        end
        
    end
    
    methods (Access = public)
        
        function newentry(app, field, values)
            
            id = values.id;
            descr = values.descr;
            author = values.author;
            email = values.email;
            
            setbuttons(app, "on")
            
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);    
            
            switch field
                case 'project'
                    mysql(['INSERT INTO projects (projectid, author, shortdescr) ' ...
                            'VALUES( "' id '",  "' author '",  "' descr '") ' ] );
                    mysql('close')
                    
                    app.fydsaved.project = id;
                    lng = length(app.DB.projects);
                    app.DB.projects{lng+1} = id;
                
                    app.ProjectDropDown.Items = app.DB.projects;
                    app.ProjectDropDown.Value = id;
                    initdataset(app);
                    initsubject(app);
                    initstim(app)                  
                    
                case 'dataset'
                    projectidx = mysql(['SELECT idx FROM projects where projectid="' app.fydsaved.project '";']);
                    mysql(['INSERT INTO datasets (datasetid, project, projectidx, shortdescr) ' ...
                            'VALUES( "' id '", "' app.fydsaved.project '", "' projectidx{1} '", "' descr '") ' ]);
                    % a default condition   
                    mysql(['INSERT INTO conditions (conditionid, dataset, project) ' ...
                            'VALUES( "none", "' id '", "' app.fydsaved.project '")' ])
                        
                    mysql('close')
                    app.fydsaved.dataset = id;
                    lng = length(app.DB.datasets);
                    app.DB.datasets{lng+1} = id; 
                    app.DatasetDropDown.Items = app.DB.datasets;
                    app.DatasetDropDown.Value = id;
                    
                    app.fydsaved.condition = 'none';
                    app.DB.conditions{1} = 'none'; 
                    app.ConditionDropDown.Items = app.DB.conditions;
                    
                    
                    app.DatasetDropDown.Enable = 'on';
                    app.ConditionDropDown.Enable = 'on';
                    
                case 'condition'
                    QUERY = ['INSERT INTO conditions (conditionid, dataset, project) ' ...
                            'VALUES( "' id '", "' app.fydsaved.dataset '", "' app.fydsaved.project '")' ];

                    mysql(QUERY);
                    mysql('close');
                    
                    app.fydsaved.condition = id;
                    lng = length(app.DB.conditions);
                    app.DB.conditions{lng+1} = id; 
                    app.ConditionDropDown.Items = app.DB.conditions;
                    app.ConditionDropDown.Value = id;
                                
                case 'subject'
                    species = values.species;
                    sex = values.sex;
                    genotype = values.genotype;
                    
                    %is in database?
                    subidx = mysql(['SELECT idx FROM subjects WHERE subjectid = "' id '"' ] );
                    if isempty(subidx) %subject does not exist in database
                        QUERY = ['INSERT INTO subjects (subjectid, species, sex, genotype, shortdescr) ' ...
                                'VALUES( "' id '" , "' species '" , "' sex '" , "' genotype '", "' descr '")' ];    
                        mysql(QUERY);
                        
                        subidx = mysql(['SELECT idx FROM subjects WHERE subjectid = "' id '"' ] );
                    end
                    
                    projectidx = mysql(['SELECT idx FROM projects WHERE projectid = "' app.fydsaved.project '"' ] );
                    
                    QUERY = ['INSERT INTO projects_subjects (project, projectidx, subject, subjectidx) '...
                             'VALUES( "' app.fydsaved.project '", ' projectidx{1} ', "' id '", ' subidx{1} ' )'];     
                    mysql(QUERY);
                    mysql('close')
                    
                    app.fydsaved.subject = id;
                    lng = length(app.DB.subjects);                 
                    app.DB.subjects{lng+1} = id; 
                    
                    %enable if it was empty, set options and selected value
                    if lng == 0, app.SubjectDropDown.Enable = 'on'; end
                    app.SubjectDropDown.Items = app.DB.subjects;
                    app.SubjectDropDown.Value = id;
                    
                    updateoutput(app)            
                    
                case 'stimulus'
                    stimidx = mysql(['SELECT idx FROM stimulus WHERE stimulusid = "' id '"' ] );
                    if isempty(stimidx) %does not exist in database
                        mysql(['INSERT INTO stimulus ( stimulusid, shortdescr ) VALUES( "' id '", "' descr '" )'] );
                        stimidx = mysql(['SELECT idx FROM stimulus WHERE stimulusid = "' id '"' ] );
                    end
                    
                    projectidx = mysql(['SELECT idx FROM projects WHERE projectid = "' app.fydsaved.project '"' ] );
                    mysql( ['INSERT INTO projects_stimulus ( project, projectidx, stimulus, stimulusidx ) VALUES ( "' app.fydsaved.project '", ' projectidx{1} ', "' id '", ' stimidx{1} ')'] );          
                    mysql('close')
                    
                    app.fydsaved.stimulus = id;
                    lng = length(app.DB.stims);
                    app.DB.stims{lng+1} = id; 
                    
                    %enable if it was empty, set options and selected value
                    if lng == 0, app.StimulusDropDown.Enable = 'on'; end                    
                    app.StimulusDropDown.Items = app.DB.stims;
                    app.StimulusDropDown.Value = id;                    
                     
                case 'setup'
                     mysql(['INSERT INTO setups (setupid, shortdescr ) VALUES( "' id '", "' descr '")' ] ); 
                     mysql('close')    
                     
                    app.fydsaved.setup = id;
                    lng = length(app.DB.setups);                    
                    app.DB.setups{lng+1} = id;
                    
                    %enable if it was empty, set options and selected value
                    if lng == 0, app.SetupDropDown.Enable = 'on'; end
                    app.SetupDropDown.Items = app.DB.setups;
                    app.SetupDropDown.Value = id; 
                    
                case 'investigator'
                                        
                    mysql(['INSERT INTO investigator ( investigatorid, email ) VALUES( "' id '", "' email '" ) ' ] );
                    mysql('close');
                     
                    app.fydsaved.investigator = id;
                    lng = length(app.DB.investigators);
                    app.DB.investigators{lng+1} = id; 
                    
                    %enable if it was empty, set options and selected value
                    if lng == 0, app.InvestigatorDropDown.Enable = 'on'; end
                    app.InvestigatorDropDown.Items = app.DB.investigators;
                    app.InvestigatorDropDown.Value = id; 
                    
            end


        end
        
        %controls whether buttons are enabled
        function setbuttons(app, set)
            app.ProjectBtnNew.Enable = set;
            app.DatasetBtnNew.Enable = set;
            app.SubjectBtnNew.Enable = set;
            app.ConditionBtnNew.Enable = set;
            app.StimBtnNew.Enable = set;
            app.SetupBtnNew.Enable = set;
            app.InvestBtnNew.Enable = set;
        end
        
        function setsession(app, val)
            if val >= 0
                app.fydsaved.sessnr = val;
                app.Sessionnr.Value = val;
                updateoutput(app) 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datastore)
            
            app.datastore = datastore;            
            app.FYD.Theme=app.theme;

            app.DB.projects = [];
            app.DB.datasets = [];
            app.DB.subjects = [];
            app.DB.conditions = [];
            app.DB.investigators = [];
            app.DB.setups = [];
            app.DB.stims = [];
            
            %Initialization and info retrieval
            strlab = 'none';
            if exist('fydsaved.mat', 'file')
                fyd = load('fydsaved.mat', 'fydsaved');
                fyd = fyd.fydsaved;
                if isfield(fyd, 'lab') && ~isempty(fyd.lab)
                    app.fydsaved=fyd; 
                    app.fydsaved.logfile='';
                    strlab = app.fydsaved.lab;
                end
            end    
            
            if any(ismember(app.LabDropDown.Items, strlab))           
                strlab = app.fydsaved.lab;
                app.LabDropDown.Value = strlab;
            else
                uialert(app.FYD, 'Please select your lab', 'Alert')
                strlab = app.LabDropDown.Value;
                app.fydsaved.lab=strlab;
                app.fydsaved.project='';
                app.fydsaved.dataset='';
                app.fydsaved.subject='';
                app.fydsaved.condition='';
                app.fydsaved.stimulus='';
                app.fydsaved.setup='';
                app.fydsaved.investigator='';
                app.fydsaved.date = '';
                app.fydsaved.logfile='';
                app.fydsaved.sessionid='';
                app.fydsaved.sessnr=str2double(char(datetime('now', 'Format', 'HHmm')));
                app.fydsaved.version='1.0';
                app.fydsaved.path = '';
            end
            
            if getlab(app, strlab)
                % initproject tests the mysql connection
                % if the datastore returns false for the connection,
                % the user needs to enter some values by hand for the json
                % file (PLEASE BEWARE OF INVALID ENTRIES)

                if initproject(app)
                    app.fydsaved.connection=true;
                    initsetup(app)
                    initinvestigator(app)
                    
                    app.DatePicker.Value = datetime('now');            
                    app.fydsaved.date = char(app.DatePicker.Value);
                    app.Sessionnr.Value = app.fydsaved.sessnr;
                    updateoutput(app)
                else
                    app.fydsaved.connection=false;
                end
            end
            app.DatePicker.Value = datetime('now');            
            app.fydsaved.date = char(app.DatePicker.Value);
            app.Sessionnr.Value = app.fydsaved.sessnr;
            updateoutput(app)
            
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            app.fydsaved.project = app.ProjectDropDown.Value;
            app.fydsaved.dataset='';
            app.fydsaved.subject='';
            app.fydsaved.condition='';
            app.fydsaved.stimulus='';
            initdataset(app);
            initsubject(app);
            initstim(app)   
            updateoutput(app)
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            app.fydsaved.dataset = app.DatasetDropDown.Value;
            app.fydsaved.condition='';            
            initcondition(app)
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            app.fydsaved.subject = app.SubjectDropDown.Value;
            updateoutput(app)
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            app.fydsaved.condition = app.ConditionDropDown.Value;           
        end

        % Value changed function: StimulusDropDown
        function StimulusDropDownValueChanged(app, event)
            app.fydsaved.stimulus = app.StimulusDropDown.Value;           
        end

        % Value changed function: SetupDropDown
        function SetupDropDownValueChanged(app, event)
            app.fydsaved.setup = app.SetupDropDown.Value;           
        end

        % Value changed function: InvestigatorDropDown
        function InvestigatorDropDownValueChanged(app, event)
            app.fydsaved.investigator = app.InvestigatorDropDown.Value;          
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            app.fydsaved.date = char(app.DatePicker.Value);                      
            updateoutput(app)
        end

        % Callback function: CloseButton, FYD
        function FYDCloseRequest(app, event)
            fydsaved = app.fydsaved; %#ok<ADPROPLC> 
            p = fileparts(mfilename('fullpath'));
            save( fullfile(p, 'fydsaved.mat'), 'fydsaved')  
            app.datastore.fields = app.fydsaved;
            delete(app)
        end

        % Button pushed function: ProjectBtnNew
        function ProjectBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'project', app.DB.projects, pos);
            setbuttons(app, "off")
        end

        % Button pushed function: DatasetBtnNew
        function DatasetBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'dataset', app.DB.datasets, pos); 
            setbuttons(app, "off")
        end

        % Button pushed function: SubjectBtnNew
        function SubjectBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'subject', app.DB.subjects, pos); 
            setbuttons(app, "off")
        end

        % Button pushed function: ConditionBtnNew
        function ConditionBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'condition', app.DB.conditions, pos); 
            setbuttons(app, "off")            
        end

        % Button pushed function: StimBtnNew
        function StimBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'stimulus', app.DB.stims, pos); 
            setbuttons(app, "off")            
        end

        % Button pushed function: SetupBtnNew
        function SetupBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'setup', app.DB.setups, pos); 
            setbuttons(app, "off")            
        end

        % Button pushed function: InvestBtnNew
        function InvestBtnNewButtonPushed(app, event)
            pos = app.FYD.Position([1,2]);
            NewId(app, 'investigator', app.DB.investigators, pos); 
            setbuttons(app, "off")            
        end

        % Value changed function: LabDropDown
        function LabDropDownValueChanged(app, event)
            app.fydsaved.lab = app.LabDropDown.Value;
            app.fydsaved.project='';
            app.fydsaved.dataset='';
            app.fydsaved.subject='';
            app.fydsaved.condition='';
            app.fydsaved.stimulus='';
            app.fydsaved.setup='';
            app.fydsaved.investigator='';
            if getlab(app, app.fydsaved.lab)
               if initproject(app)
                   app.fydsaved.connection=true;
                   initsetup(app)
                   initinvestigator(app)
               end
            end
            updateoutput(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                json.project = app.fydsaved.project;
                json.dataset = app.fydsaved.dataset;
                json.condition = app.fydsaved.condition;
                json.subject = app.fydsaved.subject;
                json.stimulus = app.fydsaved.stimulus;
                json.setup = app.fydsaved.setup;
                json.investigator = app.fydsaved.investigator;
                json.date = app.fydsaved.date;
                json.logfile = app.fydsaved.logfile;
                json.version = '1.0';
                
                comment = inputdlg('Add a comment', 'Comment', [1 50] );
                if ~isempty(comment)
                    json.comment = comment{1};
                end
                
                if isfield(app.fydsaved, 'path') && ischar(app.fydsaved.path)
                    strpath = uigetdir(app.fydsaved.path);
                else
                    strpath = uigetdir('*');
                end
                if strpath ~= 0
                    app.fydsaved.path = strpath;
                    savejson('', json, fullfile(strpath,  [app.fydsaved.sessionid '_session.json']));
                end

                
        end

        % Value changed function: Sessionnr
        function SessionnrValueChanged(app, event)
            app.fydsaved.sessnr = app.Sessionnr.Value;
            updateoutput(app)            
        end

        % Value changed function: SessionIdEdit
        function SessionIdEditValueChanged(app, event)
            sessionid = app.SessionIdEdit.Value;
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            rec = mysql(['SELECT idx FROM sessions WHERE sessionid="' sessionid '"']);
            mysql('close')
            if ~isempty(rec)
                errordlg('This is a duplicate sessionid, Please change!')
            end
        end

        % Value changed function: LogfileEdit
        function LogfileEditValueChanged(app, event)
            app.fydsaved.logfile = app.LogfileEdit.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FYD = uifigure('Visible', 'off');
            app.FYD.CloseRequestFcn = createCallbackFcn(app, @FYDCloseRequest, true);
            app.Panel = uipanel(app.FYD);
            app.Panel.Title = 'Enter fields';
            app.ProjectDropDownLabel = uilabel(app.Panel);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.Panel);
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Enable = 'off';
            app.DatasetDropDownLabel = uilabel(app.Panel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.Panel);
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Enable = 'off';
            app.SubjectDropDownLabel = uilabel(app.Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Panel);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SubjectDropDown.Enable = 'off';
            app.ConditionDropDownLabel = uilabel(app.Panel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.Panel);
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Enable = 'off';
            app.StimulusDropDownLabel = uilabel(app.Panel);
            app.StimulusDropDownLabel.Text = 'Stimulus';
            app.StimulusDropDown = uidropdown(app.Panel);
            app.StimulusDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulusDropDownValueChanged, true);
            app.StimulusDropDown.Enable = 'off';
            app.SetupDropDownLabel = uilabel(app.Panel);
            app.SetupDropDownLabel.Text = 'Setup';
            app.SetupDropDown = uidropdown(app.Panel);
            app.SetupDropDown.ValueChangedFcn = createCallbackFcn(app, @SetupDropDownValueChanged, true);
            app.SetupDropDown.Enable = 'off';
            app.InvestigatorDropDownLabel = uilabel(app.Panel);
            app.InvestigatorDropDownLabel.Text = 'Investigator';
            app.InvestigatorDropDown = uidropdown(app.Panel);
            app.InvestigatorDropDown.ValueChangedFcn = createCallbackFcn(app, @InvestigatorDropDownValueChanged, true);
            app.InvestigatorDropDown.Enable = 'off';
            app.DateDatePickerLabel = uilabel(app.Panel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DatePicker = uidatepicker(app.Panel);
            app.DatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2020 11 1]);
            app.LogfileEditFieldLabel = uilabel(app.Panel);
            app.LogfileEditFieldLabel.Text = 'Log file';
            app.LogfileEdit = uieditfield(app.Panel, 'text');
            app.LogfileEdit.ValueChangedFcn = createCallbackFcn(app, @LogfileEditValueChanged, true);
            app.SessionIdEditFieldLabel = uilabel(app.Panel);
            app.SessionIdEditFieldLabel.Text = 'Session Id';
            app.SessionIdEdit = uieditfield(app.Panel, 'text');
            app.SessionIdEdit.ValueChangedFcn = createCallbackFcn(app, @SessionIdEditValueChanged, true);
            app.ProjectBtnNew = uibutton(app.Panel, 'push');
            app.ProjectBtnNew.ButtonPushedFcn = createCallbackFcn(app, @ProjectBtnNewButtonPushed, true);
            app.ProjectBtnNew.Text = 'New';
            app.DatasetBtnNew = uibutton(app.Panel, 'push');
            app.DatasetBtnNew.ButtonPushedFcn = createCallbackFcn(app, @DatasetBtnNewButtonPushed, true);
            app.DatasetBtnNew.Text = 'New';
            app.SubjectBtnNew = uibutton(app.Panel, 'push');
            app.SubjectBtnNew.ButtonPushedFcn = createCallbackFcn(app, @SubjectBtnNewButtonPushed, true);
            app.SubjectBtnNew.Text = 'New';
            app.ConditionBtnNew = uibutton(app.Panel, 'push');
            app.ConditionBtnNew.ButtonPushedFcn = createCallbackFcn(app, @ConditionBtnNewButtonPushed, true);
            app.ConditionBtnNew.Text = 'New';
            app.StimBtnNew = uibutton(app.Panel, 'push');
            app.StimBtnNew.ButtonPushedFcn = createCallbackFcn(app, @StimBtnNewButtonPushed, true);
            app.StimBtnNew.Text = 'New';
            app.SetupBtnNew = uibutton(app.Panel, 'push');
            app.SetupBtnNew.ButtonPushedFcn = createCallbackFcn(app, @SetupBtnNewButtonPushed, true);
            app.SetupBtnNew.Text = 'New';
            app.InvestBtnNew = uibutton(app.Panel, 'push');
            app.InvestBtnNew.ButtonPushedFcn = createCallbackFcn(app, @InvestBtnNewButtonPushed, true);
            app.InvestBtnNew.Text = 'New';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @FYDCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.LabDropDownLabel = uilabel(app.Panel);
            app.LabDropDownLabel.Text = 'Lab';
            app.LabDropDown = uidropdown(app.Panel);
            app.LabDropDown.Items = {'Roelfsemalab', 'Leveltlab', 'Willuhnlab', 'Heimellab', 'Socialbrainlab', 'Dezeeuwlab', 'Kolelab', 'vanSomerenlab', 'Saltalab', 'Lohmannlab', 'Kamermanslab', 'Kalsbeeklab', 'Verhagenlab', 'Siclarilab', 'Huitingalab', 'Examplelab'};
            app.LabDropDown.ValueChangedFcn = createCallbackFcn(app, @LabDropDownValueChanged, true);
            app.LabDropDown.Value = 'Roelfsemalab';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.SessionnrSpinnerLabel = uilabel(app.Panel);
            app.SessionnrSpinnerLabel.Text = 'Session nr.';
            app.Sessionnr = uispinner(app.Panel);
            app.Sessionnr.Limits = [0 Inf];
            app.Sessionnr.ValueChangedFcn = createCallbackFcn(app, @SessionnrValueChanged, true);
            % Show the figure after all components are created
            app.FYD.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getFYDfields(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FYD)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FYD)
        end
    end
end