classdef LJHuub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabjournalHuubUIFigure  matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        ExporttoExcellMenu      matlab.ui.container.Menu
        UITable                 matlab.ui.control.Table
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the connection with FYD
            initDJ('leveltlab');

            % Update your labjournal
            populate(labjournal.HuubAuto);
            
            % retrieve the contents
            lj = fetch(labjournal.HuubAuto, '*');

            %Convert to a table and initialze the UItable in this app
            T = struct2table(lj);
            app.UITable.Data = T;
            app.UITable.ColumnName = T.Properties.VariableNames;
            app.UITable.ColumnSortable = true;

             % make the comment column editable
            bEdit = [false(1, length(T.Properties.VariableNames) -1) true];
            app.UITable.ColumnEditable = bEdit;

        end

        % Cell edit callback: UITable
        function updateComments(app, event)
            indices = event.Indices;
            newData = event.NewData;

            key.sessionid = app.UITable.Data.sessionid{indices(1,1)};
            update(labjournal.HuubAuto & key, 'comment', newData)

        end

        % Menu selected function: ExporttoExcellMenu
        function ExportExcell(app, event)
            T = app.UITable.Data;
            selpath = uigetdir();
            filename = fullfile(selpath, 'Dark_reared.xlsx');
            writetable(T,filename,'Sheet',1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabjournalHuubUIFigure = uifigure('Visible', 'off');
            app.LabjournalHuubUIFigure.Name = 'Labjournal Huub';
            app.Menu = uimenu(app.LabjournalHuubUIFigure);
            app.Menu.Text = 'Menu';
            app.ExporttoExcellMenu = uimenu(app.Menu);
            app.ExporttoExcellMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportExcell, true);
            app.ExporttoExcellMenu.Text = 'Export to Excell';
            app.UITable = uitable(app.LabjournalHuubUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @updateComments, true);
            % Show the figure after all components are created
            app.LabjournalHuubUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LJHuub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabjournalHuubUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabjournalHuubUIFigure)
        end
    end
end