classdef fydAccess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Tree                    matlab.ui.container.CheckBoxTree
        DisplayButton           matlab.ui.control.Button
        labdd                   matlab.ui.control.DropDown
        labLabel                matlab.ui.control.Label
        stimulusdd              matlab.ui.control.DropDown
        stimulusDropDownLabel   matlab.ui.control.Label
        subjectdd               matlab.ui.control.DropDown
        subjectDropDownLabel    matlab.ui.control.Label
        exconddd                matlab.ui.control.DropDown
        excondDropDownLabel     matlab.ui.control.Label
        datasetdd               matlab.ui.control.DropDown
        datasetDropDownLabel    matlab.ui.control.Label
        projectdd               matlab.ui.control.DropDown
        projectDropDownLabel    matlab.ui.control.Label
        Filetype_ed             matlab.ui.control.EditField
        FiletypeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        dbpar % Description
        DB % Description
        datastore % output values to calling environment
        fydcache % fyd fields
        AllSubjects % Description
        AllStimuli % Description
        folders % Description
        files % Description
        filter % Description
    end
    
    properties (Access = public)
        urls % Description
    end
    
    methods (Access = public)
        

    end
    
    methods (Access = private)

        
        function valid = getlab(app, strlab)

            valid = false;
            if strcmp(strlab, 'roelfsemalab') && exist('nhi_fyd_VCparms.m', 'file')
                app.dbpar = nhi_fyd_VCparms();
                valid = true;
            elseif strcmp(strlab, 'leveltlab') && exist('nhi_fyd_MVPparms.m', 'file')  
                app.dbpar = nhi_fyd_MVPparms();
                valid = true;
            elseif strcmp(strlab, 'willuhnlab') && exist('nhi_fyd_NandBparms.m', 'file') 
                app.dbpar = nhi_fyd_NandBparms();
                valid = true;
            elseif strcmp(strlab, 'heimellab') && exist('nhi_fyd_CSFparms.m', 'file')     
                app.dbpar = nhi_fyd_CSFparms();
                valid = true;
            elseif strcmp(strlab, 'socialbrainlab') && exist('nhi_fyd_SBLparms.m', 'file')  
                app.dbpar = nhi_fyd_SBLparms();
                valid = true;
            elseif strcmp(strlab, 'dezeeuwlab') && exist('nhi_fyd_CCCparms.m', 'file') 
                app.dbpar = nhi_fyd_CCCparms();
                valid = true;
            elseif strcmp(strlab, 'kolelab') && exist('nhi_fyd_AXSparms.m', 'file')  
                app.dbpar = nhi_fyd_AXSparms();
                valid = true;
            elseif strcmp(strlab, 'vansomerenlab') && exist('nhi_fyd_SandCparms.m', 'file') 
                app.dbpar = nhi_fyd_SandCparms();
                valid = true;
            elseif strcmp(strlab, 'saltalab') && exist('nhi_fyd_NandNparms.m', 'file') 
                app.dbpar = nhi_fyd_NandNparms();
                valid = true;
            elseif strcmp(strlab, 'lohmannlab') && exist('nhi_fyd_SNDparms.m', 'file') 
                app.dbpar = nhi_fyd_SNDparms();
                valid = true;
            elseif strcmp(strlab, 'kamermanslab') && exist('nhi_fyd_RSPparms.m', 'file') 
                app.dbpar = nhi_fyd_RSPparms();
                valid = true;      
            elseif strcmp(strlab, 'kalsbeeklab') && exist('nhi_fyd_HYPparms.m', 'file') 
                app.dbpar = nhi_fyd_HYPparms();
                valid = true; 
            elseif strcmp(strlab, 'verhagenlab') && exist('nhi_fyd_NRGparms.m', 'file') 
                app.dbpar = nhi_fyd_NRGparms();
                valid = true; 
            elseif strcmp(strlab, 'testlab') && exist('nhi_fyd_TMparms.m', 'file') 
                app.dbpar = nhi_fyd_TMparms();
                valid = true;
            else
                uialert(app.FYD, ["No parameterfile found for: " strlab "."...
                                  "Please contact Chris van der Togt to obtain this file." ...
                                  "email:c.vandertogt@nin.knaw.nl"], 'Error')
            end
        end
        
        function initproject(app)
            %app.Panel.Title = ['Enter fields for ' app.dbpar.Database]; 
             
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.projects = mysql('SELECT projectid FROM projects');
            mysql('close')
            
            if ~isempty(app.DB.projects)
                app.projectdd.Items = sort(app.DB.projects);
                app.projectdd.Enable = 'on';
                if ~isempty(app.fydcache.project) && any(ismember(app.DB.projects, app.fydcache.project))
                    app.projectdd.Value = app.fydcache.project;
                else
                    app.fydcache.project = app.projectdd.Value;
                end
                initdataset(app);
                initsubject(app);
                initstim(app)
            else
                app.projectdd.Items = {};
                app.projectdd.Enable = 'off';
                app.datasetdd.Items = {};
                app.datasetdd.Enable = 'off';
                app.exconddd.Items = {};
                app.exconddd.Enable = 'off';   
                app.subjectdd.Items = {};
                app.subjectdd.Enable = 'off';
                app.stimulusdd.Items = {};
                app.stimulusdd.Enable = 'off';
            end
        end
        
        function initdataset(app)
            
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.datasets = mysql(['SELECT datasetid FROM datasets WHERE project= "' app.fydcache.project '"']);
                        
            mysql('close')
            if ~isempty(app.DB.datasets)
                app.datasetdd.Items = app.DB.datasets;
                app.datasetdd.Enable = 'on';
                 if ~isempty(app.fydcache.dataset) && any(ismember(app.DB.datasets, app.fydcache.dataset))
                    app.datasetdd.Value = app.fydcache.dataset;
                 else 
                    app.fydcache.dataset = app.datasetdd.Value;
                 end
                initcondition(app)
            else
                app.datasetdd.Items = {};
                app.datasetdd.Enable = 'off';
                app.exconddd.Items = {};
                app.exconddd.Enable = 'off';
            end
        end
        
        function initcondition(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.conditions = mysql(['SELECT conditionid FROM conditions WHERE dataset = "' app.fydcache.dataset '"' ...
                                   'and project = "' app.fydcache.project '" ']);
            mysql('close')
            
            if ~isempty(app.DB.conditions)
                app.exconddd.Items = [ '-'; app.DB.conditions];
                app.exconddd.Enable = 'on';
                if ~isempty(app.fydcache.excond) && any(ismember(app.DB.conditions, app.fydcache.excond))
                    app.exconddd.Value = app.fydcache.excond;
                else
                    app.fydcache.excond = app.exconddd.Value;
                end
            else
                app.exconddd.Items = {};
                app.exconddd.Enable = 'off';
            end
            
        end
        
        function initsubject(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.subjects = mysql(['SELECT subject FROM projects_subjects WHERE project = "' app.fydcache.project '"']);
            app.AllSubjects = mysql(['SELECT subjectid, species, sex, genotype FROM subjects']);
            mysql('close')
            
            if ~isempty(app.DB.subjects)
                app.subjectdd.Items = [ '-'; app.DB.subjects];
                app.subjectdd.Enable = 'on';
                if ~isempty(app.fydcache.subject)  && any(ismember(app.DB.subjects, app.fydcache.subject))
                    app.subjectdd.Value = app.fydcache.subject;
                else
                    app.fydcache.subject = app.subjectdd.Value;
                end
            else
                app.subjectdd.Items = {};
                app.subjectdd.Enable = 'off';
            end
            
        end
        
        function initstim(app)
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            app.DB.stims = mysql(['SELECT stimulus FROM projects_stimulus WHERE project = "' app.fydcache.project '"']);
            app.AllStimuli = mysql(['SELECT stimulusid, shortdescr FROM stimulus']);
            
            mysql('close')
            
            if ~isempty(app.DB.stims)
                app.stimulusdd.Items = [ '-'; app.DB.stims];
                app.stimulusdd.Enable = 'on';
                if ~isempty(app.fydcache.stimulus) && any(ismember(app.DB.stims, app.fydcache.stimulus))
                    app.stimulusdd.Value = app.fydcache.stimulus;
                else
                    app.fydcache.stimulus = app.stimulusdd.Value;
                end
            else
                app.stimulusdd.Items = {};
                app.stimulusdd.Enable = 'off';
            end
            
        end
        
        function url = geturl(~, record)          
            switch record{3}
                case {'VS03', 'VS01'}
                    url = fullfile(strrep(record{1}, 'mnt', ''));
                    
                otherwise
                    url = fullfile(strrep(record{1}, './', ['\\' record{3} '\']));
            end
            url = fileparts(url);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datastore)
            
            app.datastore = datastore;            
           
            app.DB.projects = [];
            app.DB.datasets = [];
            app.DB.subjects = [];
            app.DB.conditions = [];
            app.DB.investigators = [];
            app.DB.setups = [];
            app.DB.stims = [];
            
            %Initialization and info retrieval
            strlab = '-';
            if exist('fydcache.mat', 'file')
                fyd = load('fydcache.mat');
                app.fydcache = fyd.fydcache; 
                if ~isempty(fyd.fydcache)
                    strlab = app.fydcache.lab;
                end
            end 
            if any(ismember(app.labdd.Items, strlab))           
                %strlab = app.fydcache.lab;
                app.labdd.Value = strlab;
            else
                uialert(app.UIFigure, 'Not a valid database, using default.', 'Warning')
                strlab = app.labdd.Value;
                app.fydcache.lab=strlab;
                app.fydcache.project='';
                app.fydcache.dataset='';
                app.fydcache.subject='';
                app.fydcache.excond='';
                app.fydcache.stimulus='';
                app.fydcache.setup='';
               
            end
            
            if getlab(app, strlab)
                initproject(app)
            end
            
            %updateoutput(app)
            
        end

        % Value changed function: labdd
        function labddValueChanged(app, event)
            app.fydcache.lab = app.labdd.Value;
            app.fydcache.project='';
            app.fydcache.dataset='';
            app.fydcache.subject='';
            app.fydcache.excond='';
            app.fydcache.stimulus='';

            if getlab(app, app.fydcache.lab)
                initproject(app)
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fydcache = app.fydcache; %#ok<ADPROPLC> 
            save fydcache.mat fydcache  
            % app.datastore.fields = app.fydcache;
            delete(app)
            
        end

        % Value changed function: projectdd
        function projectddValueChanged(app, event)
            app.fydcache.project = app.projectdd.Value;
            app.fydcache.dataset ='';
            app.fydcache.subject ='';
            app.fydcache.excond ='';
            app.fydcache.stimulus ='';
            initdataset(app);
            initsubject(app);
            initstim(app);   
        end

        % Value changed function: datasetdd
        function datasetddValueChanged(app, event)
            app.fydcache.dataset = app.datasetdd.Value;
            app.fydcache.excond =''; 
            initcondition(app);
        end

        % Value changed function: exconddd
        function excondddValueChanged(app, event)
            app.fydcache.excond = app.exconddd.Value;
            
        end

        % Value changed function: subjectdd
        function subjectddValueChanged(app, event)
            app.fydcache.subject = app.subjectdd.Value;
            
        end

        % Value changed function: stimulusdd
        function stimulusddValueChanged(app, event)
            app.fydcache.stimulus = app.stimulusdd.Value;
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            % retrieve sessions based on selected fields
            
            flds = fieldnames(app.fydcache);
            vals = struct2cell(app.fydcache);
            for i=1:length(app.folders)
                delete(app.folders(i))
            end
%              for i=1:length(app.files)
%                 delete(app.files(i))
%             end           
            
            query = [];
            for i = 1:length(flds)
                fld = flds{i};
                val = vals{i};
                if strcmp(val, '-') || isempty(val) || strcmp(fld, 'lab')
                else
                    if isempty(query)
                        query = [fld ' = "' val '"'];
                    else
                        query = [query ' AND ' fld ' = "' val '"'];
                    end 
                end
            end
            dgc = mysql('open', app.dbpar.Server, app.dbpar.User, app.dbpar.Passw);
            dbdb = mysql('use', app.dbpar.Database);
            records = mysql(['SELECT url, sessionid, server FROM sessions WHERE ' query ]);           
            mysql('close')
            
            Cnt = 1;
            if ~isempty(records) 
                app.urls = struct('url', [], 'id', []); 
                app.folders = uitreenode(app.Tree);
                app.files = struct('node', []);
                
                for i = 1:size(records,1)
                        app.urls(i).url = geturl(app, records(i,:));
                        app.urls(i).id =  records{i,2};
                        if i ~= 1
                            app.folders(i) = uitreenode(app.Tree);
                        end
                        app.folders(i).Text = app.urls(i).id;
                        filesIn = dir([app.urls(i).url app.filter]);
                        
                        for j = 1:length(filesIn)
                            if ~filesIn(j).isdir
                                app.files(Cnt).node = uitreenode(app.folders(i));
                                app.files(Cnt).node.Text = filesIn(j).name;
                                Cnt = Cnt + 1;
                            end
                        end
                end

            end
            
            % Assign Checked Nodes
            %app.Tree.CheckedNodes = [app.Node2, app.Node3, app.Node4, app.recordNode];
            

        end

        % Value changed function: Filetype_ed
        function Filetype_edValueChanged(app, event)
            app.filter = app.Filetype_ed.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FiletypeEditFieldLabel = uilabel(app.UIFigure);
            app.FiletypeEditFieldLabel.Text = 'File type';
            app.Filetype_ed = uieditfield(app.UIFigure, 'text');
            app.Filetype_ed.ValueChangedFcn = createCallbackFcn(app, @Filetype_edValueChanged, true);
            app.projectDropDownLabel = uilabel(app.UIFigure);
            app.projectDropDownLabel.Text = 'project';
            app.projectdd = uidropdown(app.UIFigure);
            app.projectdd.Items = {'-'};
            app.projectdd.ValueChangedFcn = createCallbackFcn(app, @projectddValueChanged, true);
            app.projectdd.Value = '-';
            app.datasetDropDownLabel = uilabel(app.UIFigure);
            app.datasetDropDownLabel.Text = 'dataset';
            app.datasetdd = uidropdown(app.UIFigure);
            app.datasetdd.Items = {'-'};
            app.datasetdd.ValueChangedFcn = createCallbackFcn(app, @datasetddValueChanged, true);
            app.datasetdd.Placeholder = 'select dataset';
            app.datasetdd.Value = '-';
            app.excondDropDownLabel = uilabel(app.UIFigure);
            app.excondDropDownLabel.Text = 'excond';
            app.exconddd = uidropdown(app.UIFigure);
            app.exconddd.Items = {'-'};
            app.exconddd.ValueChangedFcn = createCallbackFcn(app, @excondddValueChanged, true);
            app.exconddd.Placeholder = 'select condition';
            app.exconddd.Value = '-';
            app.subjectDropDownLabel = uilabel(app.UIFigure);
            app.subjectDropDownLabel.Text = 'subject';
            app.subjectdd = uidropdown(app.UIFigure);
            app.subjectdd.Items = {'-'};
            app.subjectdd.ValueChangedFcn = createCallbackFcn(app, @subjectddValueChanged, true);
            app.subjectdd.Placeholder = 'select subject';
            app.subjectdd.Value = '-';
            app.stimulusDropDownLabel = uilabel(app.UIFigure);
            app.stimulusDropDownLabel.Text = 'stimulus';
            app.stimulusdd = uidropdown(app.UIFigure);
            app.stimulusdd.Items = {'-'};
            app.stimulusdd.ValueChangedFcn = createCallbackFcn(app, @stimulusddValueChanged, true);
            app.stimulusdd.Placeholder = 'select stimulus';
            app.stimulusdd.Value = '-';
            app.labLabel = uilabel(app.UIFigure);
            app.labLabel.Text = 'lab';
            app.labdd = uidropdown(app.UIFigure);
            app.labdd.Items = {'roelfsemalab', 'leveltlab', 'willuhnlab', 'heimellab', 'socialbrainlab', 'dezeeuwlab', 'kolelab', 'vansomerenlab', 'Saltalab', 'lohmannlab', 'kamermanslab', 'kalsbeeklab', 'verhagenlab', 'testlab'};
            app.labdd.ValueChangedFcn = createCallbackFcn(app, @labddValueChanged, true);
            app.labdd.Value = 'roelfsemalab';
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fydAccess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end